classdef calcium_simulation_setup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        dtmsLabel                 matlab.ui.control.Label
        dt                        matlab.ui.control.NumericEditField
        ShellScript               matlab.ui.control.TextArea
        GenerateScriptButton      matlab.ui.control.Button
        Clear                     matlab.ui.control.Button
        BrowseUG4                 matlab.ui.control.Button
        Label_5                   matlab.ui.control.Label
        UG4exe                    matlab.ui.control.EditField
        endtimemsLabel            matlab.ui.control.Label
        endtime                   matlab.ui.control.NumericEditField
        SaveScriptButton          matlab.ui.control.Button
        RunScript                 matlab.ui.control.Button
        ConvertNEURONfilesButton  matlab.ui.control.Button
        UG4fileLocationLabel      matlab.ui.control.Label
        endtimeCheckBox           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        scriptmakerpath ; % this is for the fullpath of the scriptmaker file
        model1folderpath; % this is for the model1folderpath
        simulationendtime;   % this is for using default endtime in tvec.dat
        exportstatus;
        voltagesamplerate;
    end
    
    methods (Access = private)
        
        function getVoltageSampleDT(app)
            neuronpath = fullfile(app.model1folderpath,'Results','NEURON');
            tvecpath = fullfile(app.model1folderpath,'Results','NEURON','tvec.dat');
            fig = app.UIFigure;
            
            if(not(isfolder(neuronpath)))
                % this checks if NEURON has been run
                app.voltagesamplerate=0;
                message = sprintf("NEURON simulation hasn't been run! \n The Results/NEURON folder is missing. \n Please run NEURON before running the calcium simulation.");
                uialert(fig,message,"Missing NEURON results")
            elseif (~exist(tvecpath,'file'))
                % this checks for tvec.dat
                app.voltagesamplerate=0;
                message = sprintf("Missing  Results/NEURON/tvec.dat file.\n Please run NEURON before running the calcium simulation.");
                uialert(fig,message,"Missing NEURON results")
            else
                tvec = readmatrix(tvecpath);
                % get the voltage sample rate and conver to seconds
                % i.e. if difference between two times is 0.025 [ms]
                % it is 0.025 *1e-3 [s], this is assuming the tvec.dat
                % file is in [ms]
                app.voltagesamplerate=abs(tvec(2)-tvec(1))*1e-3;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.scriptmakerpath = fileparts(which('calcium_simulation_setup.mlapp')); % this is the full path of the scriptmaker app
            fsepindex = regexp(app.scriptmakerpath,filesep);  % this extracts all the indices of the filesep '\' or '/' 
            app.model1folderpath = app.scriptmakerpath(1:fsepindex(end-1)-1); % this gets the path for the Model folder without filesep
            
            % this constructs the voltage data path
            vpath = fullfile(app.model1folderpath,'Results','Calcium','Converted_Voltage_Traces');
                        
            % this constructs the output data path
            opath = fullfile(app.model1folderpath,'Results','Calcium','Calcium_Simulation_Results');
            
            % this checks for Calcium simulation results folder
            if(not(isfolder(opath)))
                mkdir(opath);
            end
            
            % this checks for Converted voltage trace folder
            if(not(isfolder(vpath)))
                mkdir(vpath);
            end
            
            % this checks if the Results folder and subfolders exists, if
            % not then it makes them
            if (not(isfolder(fullfile(app.model1folderpath,'Results'))))
                mkdir(fullfile(app.model1folderpath,'Results'));
                mkdir(fullfile(app.model1folderpath,'Results','Calcium'));
                mkdir(vpath);
                mkdir(opath);
            end
            
            % Load the location of ug4 for future use
            load_ug4_path = fullfile(fileparts(app.model1folderpath),'ug4_path.txt');
            if isfile(load_ug4_path)
                fileID = fopen(load_ug4_path,'r');
                ug4_path = fscanf(fileID,'%s');
                fclose(fileID);
                
                % Find the file name of the selected UG4 path
                if ~isempty(ug4_path)
                    [~, ug4_file, ~] = fileparts(ug4_path);
                end
                
                % Check file
                if ~isempty(ug4_path) && isfile(ug4_path) && strcmpi(ug4_file,'ugshell')
                    app.UG4exe.Value = ug4_path;
                end
            end
            
            app.getVoltageSampleDT;
        end

        % Button pushed function: GenerateScriptButton
        function GenerateScript(app, event)
            % this constructs the path for the lua script
            luapath = fullfile(app.model1folderpath,'Code','Calcium','vdccFullCellCalcium.lua');
            
            % this constructs the voltage data path
            vpath = fullfile(app.model1folderpath,'Results','Calcium','Converted_Voltage_Traces');
                        
            % this constructs the output data path
            opath = fullfile(app.model1folderpath,'Results','Calcium','Calcium_Simulation_Results');
            
            % this makes the neuron path folder
            neuronpath = fullfile(app.model1folderpath,'Results','NEURON');
            
            % this checks if the Results folder and subfolders exists, if
            % not then it makes them
            if (not(isfolder(fullfile(app.model1folderpath,'Results'))))
                mkdir(fullfile(app.model1folderpath,'Results'));
                mkdir(fullfile(app.model1folderpath,'Results','Calcium'));
                mkdir(vpath);
                mkdir(opath);
            end
            
            fig = app.UIFigure;
            
            % This constructs the geometry .swc path
            geometryList = dir(fullfile(app.model1folderpath,'Results','Calcium', '*.swc'));
            %dir(fullfile(app.model1folderpath,'Results','Calcium','*.swc'));
            
            % Find the file name of the selected UG4 path
            if ~isempty(app.UG4exe.Value)
                [~, ug4_file, ~] = fileparts(app.UG4exe.Value);
            end
                        
            % this does some file checks
            if (isempty(geometryList) == 1 || isempty(app.dt.Value)==1 || isempty(app.endtime) == 1)         
                % this checks of the geometry file is missing or the dt,
                % endtime are incorrect or empty
                message = sprintf('Missing the following:\n -ugshell binary path, or \n -geometry .swc file, or \n -dt time step size, or \n -endtime not specified.');
                uialert(fig,message,'Invalid Input');
            elseif isempty(app.UG4exe.Value) || ~isfile(app.UG4exe.Value) || ~strcmpi(ug4_file,'ugshell')
                % this checks if the ugshell field is entered
                message = sprintf("Invalid UG4 path!");
                uialert(fig,message,'Invalid UG4 path');
            elseif(not(isfolder(neuronpath)))
                % this checks if NEURON has been run
                message = sprintf("NEURON simulation hasn't been run! \n Please run NEURON before running the calcium simulation.");
                uialert(fig,message,"Missing NEURON results")
            %elseif (isempty(dir(fullfile(vpath, '*.dat')))==1)
                % this checks if the voltage data is available
            %    message = sprintf('Missing the Voltage Data or voltage data is incomplete. \n Run or reRun NEURON before running the calcium simulation.');
            %    uialert(fig,message,'Missing Voltage Data');
            
            % this check the export status of the NEURON results
            elseif (app.exportstatus ~=2)
                if (app.exportstatus == 1)
                    message = sprintf('Incomplete Data.\nPlease rerun NEURON simulation and let it finish writing the data to the file.\nThe NEURON window automatically closes upon completion.');
                    uialert(fig,message,sprintf('Error: Export Status %i',app.exportstatus),'Icon','error');
                else
                    message = sprintf('Missing Input files.\nPlease run NEURON before running the calcium simulation.');
                    uialert(fig,message,sprintf('Error: Export Status %i',app.exportstatus),'Icon','error');
                end
            else
                gridSWC = fullfile(geometryList(1).folder,geometryList(1).name);
                app.simulationendtime=app.endtime.Value;
                
                % this part concatenates all the strings that are saved to
                % the output box, this is the actual script commands for
                % running the UG4 simulation
                scriptText='';
                scriptText=strcat(scriptText, app.UG4exe.Value);
                scriptText=strcat(scriptText," -ex ");
                scriptText=strcat(scriptText, luapath);
                scriptText=strcat(scriptText," -grid ");
                scriptText=strcat(scriptText, gridSWC);
                scriptText=strcat(scriptText," -numRefs 0 -setting none ");
                scriptText=strcat(scriptText,sprintf(" -dt %0.7f",app.dt.Value*1e-3)); % note converting ms to s here
                scriptText=strcat(scriptText,sprintf(" -endTime %0.7f",app.simulationendtime*1e-3)); % note converting ms to s here
                scriptText=strcat(scriptText," -vtk -pstep 1.0e-5 -vmData ");
                scriptText=strcat(scriptText,vpath);
                scriptText=strcat(scriptText," -outName ");
                scriptText=strcat(scriptText,opath);
                scriptText=strcat(scriptText,sprintf(" -solver GS -minDef 1e-11 -numNewton 5 -vSampleRate %0.7f ",app.voltagesamplerate));
                scriptText=strrep(scriptText,"\","/");
                app.ShellScript.Value=scriptText;
                
                save_ug4_path = fileparts(app.model1folderpath);
                fileID = fopen(fullfile(save_ug4_path,'ug4_path.txt'),'w');
                % Save the location of ug4 for future use
                ug4_path = strrep(app.UG4exe.Value, '/', '//');
                ug4_path = strrep(ug4_path, '\', '\\');
                fprintf(fileID,ug4_path);
                fclose(fileID);
            end
        end

        % Button pushed function: Clear
        function ClearScript(app, event)
            app.ShellScript.Value='';
        end

        % Button pushed function: BrowseUG4
        function SearchUG4Folder(app, event)
            [file,path] = uigetfile('*.*', 'Select UG4 file location');
            if (path == 0)
                app.UG4exe.Value= 'Browse for the UG4 executable';
            else
                app.UG4exe.Value=fullfile(path,file);
            end
        end

        % Button pushed function: SaveScriptButton
        function SaveScript(app, event)
            % this constructs the output data path
            opath = fullfile(app.model1folderpath,'Results','Calcium','Calcium_Simulation_Results');
            % deletes old output data
            if isfolder(opath)
                rmdir(opath, 's');
            end
            % makes the output director again
            mkdir(opath);
            
            % this makes the shell script file name
            if (ispc == 1)
                outfilename = strcat('calciumshellscript.ps1');
            else
                outfilename = strcat('calciumshellscript.sh');
            end
                       
            % this makes the full file path to the shell script
            filename = fullfile(app.model1folderpath,'Results','Calcium',outfilename);
            fileID = fopen(filename,'w');
            
            % this write what is inside the text box to the shell script
            % file defined above
            my_str = string(app.ShellScript.Value);
            fprintf(fileID,my_str);
            fclose(fileID);
        end

        % Button pushed function: RunScript
        function RunShellScript(app, event)
            if (app.ShellScript.Value=="")
                % this checks if the script has been generated
                fig = app.UIFigure;
                message = sprintf("Plesae Generate the shell script.");
                uialert(fig,message,'Empty Shell Script');
            else
                % this makes the shell script file name
                if (ispc == 1)
                    outfilename = strcat('calciumshellscript.ps1');
                else
                    outfilename = strcat('calciumshellscript.sh');
                end
                
                % this constructs the output data path
                opath = fullfile(app.model1folderpath,'Results','Calcium','Calcium_Simulation_Results');
                % deletes old output data
                if isfolder(opath)
                    rmdir(opath, 's');
                end
               
                % makes the output director again
                mkdir(opath);                
                                
                % this makes the full file path to the shell script
                filename = fullfile(app.model1folderpath,'Results','Calcium',outfilename);
                fileID = fopen(filename,'w');
                
                % this write what is inside the text box to the shell script
                % file defined above
                my_str = string(app.ShellScript.Value);
                fprintf(fileID,my_str);
                fclose(fileID);
                
                % progress bar of calcium simulation
                fig = app.UIFigure;
                d = uiprogressdlg(fig,'Title','Running Calcium Simulation','Indeterminate','on');
                drawnow
                % this executes the system call by running the shell script
                % using the sh command
                if (ispc == 1)
                    [status,cmdout]=system(sprintf('powershell -inputformat none -file %s',filename),'-echo');
                    %close(d)
                else 
                    [status,cmdout]=system(sprintf('sh %s',filename),'-echo');
                    %close(d)
                end
                
                close(d)
                
                % check for ABORT code in ug4 simulation
                ugERR = contains(cmdout,"ABORT");
                
                % if no abort simulation is successful
                if (status == 0 && ugERR == false)
                    message = sprintf('Calcium Simulation Completed!');
                    uialert(fig,message,'Success','Icon','success');
                else
                    % show exception in error alert box
                    errSTR = extractAfter(cmdout,"EXCEPTION");
                    message = sprintf('Calcium Simulation Error!\n%s',errSTR);
                    uialert(fig,message,'Error','Icon','error');
                end
                
            end
        end

        % Button pushed function: ConvertNEURONfilesButton
        function ConvertNEURONfilesButtonPushed(app, event)
            % this makes the neuron path folder
            neuronpath = fullfile(app.model1folderpath,'Results','NEURON');
            fig = app.UIFigure;
            
            % this constructs the voltage directory data path
            vpath = fullfile(app.model1folderpath,'Results','Calcium','Converted_Voltage_Traces');
            tvecfile = fullfile(app.model1folderpath,'Results','Calcium','tvec.dat');
            swcfile = fullfile(app.model1folderpath,'Results','Calcium','neuron_out.swc');
            % deletes old voltage directory
            if isfolder(vpath)
                rmdir(vpath, 's'); 
            end
            if isfile(tvecfile)
                delete(tvecfile);  % deletes the tvec file from previous conversion
            end
            if isfile(swcfile)
                delete(swcfile);   % deletes the swc file from previous conversion
            end
            % makes the voltage direcotry again
            mkdir(vpath);    
            
            if(not(isfolder(neuronpath)))
                % this checks if NEURON has been run
                message = sprintf("NEURON simulation hasn't been run! \n Please run NEURON before running the calcium simulation.");
                uialert(fig,message,"Missing NEURON results")
            else
                %the NEURON simulation should be already done, but the swc file 
                %and the converted voltage traces wouldn't exist, so you need
                %to first run export_data to generate the .swc and voltage data
                %files which are fed into the calcium simulation.
                fig = app.UIFigure;
                d = uiprogressdlg(fig,'Title','Exporting Data','Indeterminate','on');
                drawnow
                app.exportstatus = export_data;
                close(d)
                
                if (app.exportstatus == 2)
                    message = sprintf('NEURON data conversion successful');
                    uialert(fig,message,'Success','Icon','success');
                elseif (app.exportstatus == 1)
                    message = sprintf('Incomplete Voltage.\nPlease rerun NEURON simulation and let it finish writing the data to the file.\nThe NEURON window automatically closes upon completion.');
                    uialert(fig,message,sprintf('Error: Export Status %i',app.exportstatus),'Icon','error');
                else
                    message = sprintf('Missing Input files.\nPlease run NEURON before running the calcium simulation.');
                    uialert(fig,message,sprintf('Error: Export Status %i',app.exportstatus),'Icon','error');
                end
            end
        end

        % Value changed function: endtimeCheckBox
        function endtimeCheckBoxValueChanged(app, event)
            value = app.endtimeCheckBox.Value;
            neuronpath = fullfile(app.model1folderpath,'Results','NEURON');
            tvecpath = fullfile(app.model1folderpath,'Results','NEURON','tvec.dat');
            fig = app.UIFigure;
            
            if(not(isfolder(neuronpath)))
                % this checks if NEURON has been run
                app.endtimeCheckBox.Value=0;
                message = sprintf("NEURON simulation hasn't been run! \n The Results/NEURON folder is missing. \n Please run NEURON before running the calcium simulation.");
                uialert(fig,message,"Missing NEURON results")
            elseif (~exist(tvecpath,'file'))
                % this checks for tvec.dat
                app.endtimeCheckBox.Value=0;
                message = sprintf("Missing  Results/NEURON/tvec.dat file.\n Please run NEURON before running the calcium simulation.");
                uialert(fig,message,"Missing NEURON results")
            else
                if (value==1)
                    %fprintf("Use default dt!\n")
                    tvec = readmatrix(tvecpath);
                    % convert the endtime to seconds, it is in ms
                    app.endtime.Value = tvec(end);
                    % blurs out the text box until the user unchecks the
                    % checkbox
                    app.endtime.Editable='off';
                    app.endtime.FontColor=[0.75 0.75 0.75];
                else
                    %fprintf("Use user default")
                    app.simulationendtime= app.endtime.Value;
                    app.endtime.Editable='on';
                    app.endtime.FontColor='k';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.dtmsLabel = uilabel(app.UIFigure);
            app.dtmsLabel.Text = 'dt [ms] = ';
            app.dt = uieditfield(app.UIFigure, 'numeric');
            app.dt.Value = 0.05;
            app.ShellScript = uitextarea(app.UIFigure);
            app.GenerateScriptButton = uibutton(app.UIFigure, 'push');
            app.GenerateScriptButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateScript, true);
            app.GenerateScriptButton.Text = 'Generate Script';
            app.Clear = uibutton(app.UIFigure, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearScript, true);
            app.Clear.Text = 'Clear';
            app.BrowseUG4 = uibutton(app.UIFigure, 'push');
            app.BrowseUG4.ButtonPushedFcn = createCallbackFcn(app, @SearchUG4Folder, true);
            app.BrowseUG4.Text = 'Browse';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.UG4exe = uieditfield(app.UIFigure, 'text');
            app.endtimemsLabel = uilabel(app.UIFigure);
            app.endtimemsLabel.Text = 'end time [ms] = ';
            app.endtime = uieditfield(app.UIFigure, 'numeric');
            app.endtime.Value = 6;
            app.SaveScriptButton = uibutton(app.UIFigure, 'push');
            app.SaveScriptButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScript, true);
            app.SaveScriptButton.Text = 'Save Script';
            app.RunScript = uibutton(app.UIFigure, 'push');
            app.RunScript.ButtonPushedFcn = createCallbackFcn(app, @RunShellScript, true);
            app.RunScript.Text = 'Run Script';
            app.ConvertNEURONfilesButton = uibutton(app.UIFigure, 'push');
            app.ConvertNEURONfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertNEURONfilesButtonPushed, true);
            app.ConvertNEURONfilesButton.Text = 'Convert NEURON files';
            app.UG4fileLocationLabel = uilabel(app.UIFigure);
            app.UG4fileLocationLabel.Text = 'UG4 file Location';
            app.endtimeCheckBox = uicheckbox(app.UIFigure);
            app.endtimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @endtimeCheckBoxValueChanged, true);
            app.endtimeCheckBox.Text = 'Default end time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calcium_simulation_setup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end