classdef visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        OpenVoltageDataMenu  matlab.ui.container.Menu
        OpenCalciumDataMenu  matlab.ui.container.Menu
        QuitMenu             matlab.ui.container.Menu
        UIAxes               matlab.ui.control.UIAxes
        PlotButton           matlab.ui.control.Button
        msLabel              matlab.ui.control.Label
        Button_back          matlab.ui.control.Button
        Button_forward       matlab.ui.control.Button
        tEditFieldLabel      matlab.ui.control.Label
        tEditField           matlab.ui.control.NumericEditField
        StatusPanel          matlab.ui.container.Panel
        Label                matlab.ui.control.Label
        StepsLabel           matlab.ui.control.Label
        StepsEditField       matlab.ui.control.NumericEditField
        samplesLabel         matlab.ui.control.Label
        UIAxes2              matlab.ui.control.UIAxes
        MaxEditFieldLabel    matlab.ui.control.Label
        MaxEditField         matlab.ui.control.NumericEditField
        MinEditFieldLabel    matlab.ui.control.Label
        MinEditField         matlab.ui.control.NumericEditField
        RangeLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        condition
        data
        data_raw
        tvec
        Xc
        Yc
        Zc
        Xp
        Yp
        Zp
        cmap
        cax
        a
        b
    end
    
    methods (Access = private)
        
        function plot_neuron(app)
            t = app.tEditField.Value;
            [~,time_idx] = min(abs(app.tvec-repmat(t,length(app.tvec),1)));
            if isinf(app.a)
                color_idx = 1;
            else
                color_idx = round(app.a*app.data(2:end,time_idx)+app.b);
            end
            colors = app.cmap(color_idx,:);
            [caz,cel] = view(app.UIAxes);
            h = plot3(app.UIAxes,[app.Xc app.Xp]',[app.Yc app.Yp]',[app.Zc app.Zp]','Linewidth',3);
            view(app.UIAxes, [caz,cel]);
            set(h, {'color'}, num2cell(colors,2));
            title(app.UIAxes, ['t = ' num2str(app.tvec(time_idx)) ' ms'], 'FontSize', 16);
            app.tEditField.Value = app.tvec(time_idx);
        end
        
        function plot_soma(app)
            t = app.tEditField.Value;
            [~,time_idx] = min(abs(app.tvec-repmat(t,length(app.tvec),1)));
            plot(app.UIAxes2, app.tvec, app.data_raw(1,:), 'Linewidth',3);
            hold(app.UIAxes2, "on");
            xline(app.UIAxes2, app.tvec(time_idx), 'r--', 'Linewidth', 2);
            hold(app.UIAxes2, "off");
        end
        
        function update_range(app)
            % Saturate data for better visualization
            app.data = app.data_raw;
            app.data(app.data < app.MinEditField.Value) = app.MinEditField.Value;
            app.data(app.data > app.MaxEditField.Value) = app.MaxEditField.Value;

            % linearly map min(data)->1, max(data)->length(color_map)
            % color_idx = a*value + b
            app.a = (length(app.cmap)-1)/(max(app.data(:))-min(app.data(:)));
            app.b = 1-(length(app.cmap)-1)*min(app.data(:))/(max(app.data(:))-min(app.data(:)));
            
            plot_neuron(app);
            
            if min(app.data(:)) == max(app.data(:))
                caxis(app.UIAxes, [min(app.data(:)) max(app.data(:))+1e-6]);
            else
                caxis(app.UIAxes, [min(app.data(:)) max(app.data(:))]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.PlotButton.Visible = 'off';
            app.Button_back.Visible = 'off';
            app.Button_forward.Visible = 'off';
            app.StepsEditField.Visible = 'off';
            app.StepsLabel.Visible = 'off';
            app.samplesLabel.Visible = 'off';
            app.tEditField.Visible = 'off';
            app.tEditFieldLabel.Visible = 'off';
            app.msLabel.Visible = 'off';
            app.MinEditField.Visible = 'off';
            app.MinEditFieldLabel.Visible = 'off';
            app.MaxEditField.Visible = 'off';
            app.MaxEditFieldLabel.Visible = 'off';
            app.RangeLabel.Visible = 'off';
            app.cax = colorbar(app.UIAxes);
            colormap(app.UIAxes,'jet');
            app.cax.Visible = 'off';
            axis(app.UIAxes, 'equal');
            box(app.UIAxes, 'on');
            set(app.UIAxes,'linewidth', 1.5);
            pos = get(app.UIAxes, 'Position');
            pos(3) = pos(3)*1.1;
            set(app.UIAxes, 'Position', pos)
            set(app.UIAxes2,'linewidth', 1.5);
            app.data = [];
            drawnow;
            set(0,'units','pixels')
            scsize = get(0,'ScreenSize'); % get screen size
            uisize = app.UIFigure.Position;
            if ((uisize(3)+uisize(1)) >= scsize(3)) || ((uisize(4)+uisize(2)) >= scsize(4))
                app.UIFigure.WindowState = 'maximized'; % maximize the window if the default size goes out of the screen
            end
            app.Label.Text = 'Ready';
        end

        % Callback function: PlotButton, tEditField
        function PlotButtonPushed(app, event)
            if ~isempty(app.data)
                app.Label.Text = 'Wait';
                drawnow
                plot_neuron(app);
                plot_soma(app);
                app.Label.Text = '';
            end
        end

        % Button pushed function: Button_back
        function Button_backPushed(app, event)
            if ~isempty(app.data)
                app.Label.Text = 'Wait';
                drawnow
                t = app.tEditField.Value;
                st = app.StepsEditField.Value;
                [~,time_idx] = min(abs(app.tvec-repmat(t,length(app.tvec),1)));
                if time_idx > st
                    time_idx = time_idx - st;
                    app.tEditField.Value = app.tvec(time_idx);
                    plot_neuron(app);
                    plot_soma(app);
                elseif time_idx ~= 1
                    time_idx = 1;
                    app.tEditField.Value = app.tvec(time_idx);
                    plot_neuron(app);
                    plot_soma(app);
                end
                app.Label.Text = '';
            end
        end

        % Button pushed function: Button_forward
        function Button_forwardPushed(app, event)
            if ~isempty(app.data)
                app.Label.Text = 'Wait';
                drawnow
                t = app.tEditField.Value;
                st = app.StepsEditField.Value;
                [~,time_idx] = min(abs(app.tvec-repmat(t,length(app.tvec),1)));
                if time_idx <= (length(app.tvec)-st)
                    time_idx = time_idx + st;
                    app.tEditField.Value = app.tvec(time_idx);
                    plot_neuron(app);
                    plot_soma(app);
                elseif time_idx ~= length(app.tvec)
                    time_idx = length(app.tvec);
                    app.tEditField.Value = app.tvec(time_idx);
                    plot_neuron(app);
                    plot_soma(app);
                end
                app.Label.Text = '';
            end
        end

        % Menu selected function: OpenVoltageDataMenu
        function OpenVoltageDataMenuSelected(app, event)
            app.Label.Text = 'Loading...';
            app.PlotButton.Visible = 'off';
            app.Button_back.Visible = 'off';
            app.Button_forward.Visible = 'off';
            app.StepsEditField.Visible = 'off';
            app.StepsLabel.Visible = 'off';
            app.samplesLabel.Visible = 'off';
            app.tEditField.Visible = 'off';
            app.tEditFieldLabel.Visible = 'off';
            app.msLabel.Visible = 'off';
            app.MinEditField.Visible = 'off';
            app.MinEditFieldLabel.Visible = 'off';
            app.MaxEditField.Visible = 'off';
            app.MaxEditFieldLabel.Visible = 'off';
            app.RangeLabel.Visible = 'off';
            drawnow
            folder_in = ['..' filesep '..' filesep 'Results' filesep 'NEURON'];
            % Load files
            if ~exist([folder_in filesep 'locs' filesep 'locs_all_seg.txt'])
                message = sprintf('Missing Input files.\nPlease run the NEURON simulation before visualization.');
                uialert(app.UIFigure,message,'Error: Missing files','Icon','error');
                app.Label.Text = '';
                drawnow
                return
            end
            locs = load([folder_in filesep 'locs' filesep 'locs_all_seg.txt']);
            
            if ~exist([folder_in filesep 'voltage_trace.dat'])
                message = sprintf('Missing Input files.\nPlease run the NEURON simulation before visualization.');
                uialert(app.UIFigure,message,'Error: Missing files','Icon','error');
                app.Label.Text = '';
                drawnow
                return
            end
            
            try
                app.data = load([folder_in filesep 'voltage_trace.dat'])';
                app.tvec = load([folder_in filesep 'tvec.dat']);
            catch
                message = sprintf('Please rerun NEURON simulation and let it finish writing the data to the file.\nThe NEURON window automatically closes upon completion.');
                uialert(app.UIFigure,message,'Error: Incomplete data','Icon','error');
                app.Label.Text = '';
                drawnow
                return
            end
            if size(app.data,2) ~= length(app.tvec)
                message = sprintf('Please rerun NEURON simulation and let it finish writing the data to the file.\nThe NEURON window automatically closes upon completion.');
                uialert(app.UIFigure,message,'Error: Incomplete data','Icon','error');
                app.Label.Text = '';
                drawnow
                return
            end
            app.data_raw = app.data;
            
            % Child coordinates
            app.Xc = locs(2:end,1);
            app.Yc = locs(2:end,2);
            app.Zc = locs(2:end,3);
            % Parent coordinates
            app.Xp = locs(2:end,4);
            app.Yp = locs(2:end,5);
            app.Zp = locs(2:end,6);
            % Data range
            app.MinEditField.Value = min(app.data(:));
            app.MaxEditField.Value = max(app.data(:));
            app.RangeLabel.Text = 'mV';
            % Color map
            app.cmap = jet(256);
%             % linearly map min(data)->1, max(data)->length(color_map)
%             % color_idx = a*value + b
%             app.a = (length(app.cmap)-1)/(max(app.data(:))-min(app.data(:)));
%             app.b = 1-(length(app.cmap)-1)*min(app.data(:))/(max(app.data(:))-min(app.data(:)));
%             plot_neuron(app);
            update_range(app);
            xlabel(app.UIAxes, 'X (\mus)', 'FontWeight', "bold");
            ylabel(app.UIAxes, 'Y (\mus)', 'FontWeight', "bold");
            zlabel(app.UIAxes, 'Z (\mus)', 'FontWeight', "bold");
            app.cax.Label.String = 'Vm (mV)';
            app.cax.Label.FontWeight = 'bold';
%             if min(app.data(:)) == max(app.data(:))
%                 caxis(app.UIAxes, [min(app.data(:)) max(app.data(:))+1e-6]);
%             else
%                 caxis(app.UIAxes, [min(app.data(:)) max(app.data(:))]);
%             end
            plot_soma(app);
            ylabel(app.UIAxes2, 'v (mV)', 'FontWeight', "bold");
            app.UIAxes.Visible = 'on';
            app.cax.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.PlotButton.Visible = 'on';
            app.Button_back.Visible = 'on';
            app.Button_forward.Visible = 'on';
            app.StepsEditField.Visible = 'on';
            app.StepsLabel.Visible = 'on';
            app.samplesLabel.Visible = 'on';
            app.tEditField.Visible = 'on';
            app.tEditFieldLabel.Visible = 'on';
            app.msLabel.Visible = 'on';
            app.MinEditField.Visible = 'on';
            app.MinEditFieldLabel.Visible = 'on';
            app.MaxEditField.Visible = 'on';
            app.MaxEditFieldLabel.Visible = 'on';
            app.RangeLabel.Visible = 'on';
            app.Label.Text = '';
            drawnow
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            app.delete;
        end

        % Menu selected function: OpenCalciumDataMenu
        function OpenCalciumDataMenuSelected(app, event)
            app.Label.Text = 'Loading...';
            app.PlotButton.Visible = 'off';
            app.Button_back.Visible = 'off';
            app.Button_forward.Visible = 'off';
            app.StepsEditField.Visible = 'off';
            app.StepsLabel.Visible = 'off';
            app.samplesLabel.Visible = 'off';
            app.tEditField.Visible = 'off';
            app.tEditFieldLabel.Visible = 'off';
            app.msLabel.Visible = 'off';
            app.MinEditField.Visible = 'off';
            app.MinEditFieldLabel.Visible = 'off';
            app.MaxEditField.Visible = 'off';
            app.MaxEditFieldLabel.Visible = 'off';
            app.RangeLabel.Visible = 'off';
            drawnow
            folder_in = ['..' filesep '..' filesep 'Results' filesep 'Calcium'];
            % Load files
            try
                swc = load([folder_in filesep 'neuron_out.swc']);
                swc(:,3:5) = swc(:,3:5)*1e6; % convert to um
                app.data = readmatrix([folder_in filesep 'Calcium_Simulation_Results' filesep 'fullCalciumData.txt'])'*1e6; % convert to umol/l
                app.data_raw = app.data;
                app.tvec = load([folder_in filesep 'Calcium_Simulation_Results' filesep 'timeSteps.txt'])*1000; % convert to ms
            catch
                message = sprintf('Missing Input files.\nPlease run the Calcium simulation before visualization.');
                uialert(app.UIFigure,message,'Error: Missing data','Icon','error');
                app.Label.Text = '';
                drawnow
                return
            end
            % Child coordinates
            app.Xc = swc(2:end,3);
            app.Yc = swc(2:end,4);
            app.Zc = swc(2:end,5);
            % Parent coordinates
            app.Xp = swc(swc(2:end,7),3);
            app.Yp = swc(swc(2:end,7),4);
            app.Zp = swc(swc(2:end,7),5);
            % Data range
            app.MinEditField.Value = min(app.data(:));
            app.MaxEditField.Value = max(app.data(:));
            app.RangeLabel.Text = [char(956) 'mol/l'];
            % Color map
            app.cmap = jet(256);
            %             % linearly map min(data)->1, max(data)->length(color_map)
            %             % color_idx = a*value + b
            %             app.a = (length(app.cmap)-1)/(max(app.data(:))-min(app.data(:)));
            %             app.b = 1-(length(app.cmap)-1)*min(app.data(:))/(max(app.data(:))-min(app.data(:)));
            %             plot_neuron(app);
            update_range(app);
            xlabel(app.UIAxes, 'X (\mus)', 'FontWeight', "bold");
            ylabel(app.UIAxes, 'Y (\mus)', 'FontWeight', "bold");
            zlabel(app.UIAxes, 'Z (\mus)', 'FontWeight', "bold");
            app.cax.Label.String = '[Ca^{2+}] (\mumol/l)';
            app.cax.Label.FontWeight = 'bold';
            %             if min(app.data(:)) == max(app.data(:))
            %                 caxis(app.UIAxes, [min(app.data(:)) max(app.data(:))+1e-6]);
            %             else
            %                 caxis(app.UIAxes, [min(app.data(:)) max(app.data(:))]);
            %             end
            plot_soma(app);
            ylabel(app.UIAxes2, '[Ca^{2+}] (\mumol/l)', 'FontWeight', "bold");
            app.UIAxes.Visible = 'on';
            app.cax.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.PlotButton.Visible = 'on';
            app.Button_back.Visible = 'on';
            app.Button_forward.Visible = 'on';
            app.StepsEditField.Visible = 'on';
            app.StepsLabel.Visible = 'on';
            app.samplesLabel.Visible = 'on';
            app.tEditField.Visible = 'on';
            app.tEditFieldLabel.Visible = 'on';
            app.msLabel.Visible = 'on';
            app.MinEditField.Visible = 'on';
            app.MinEditFieldLabel.Visible = 'on';
            app.MaxEditField.Visible = 'on';
            app.MaxEditFieldLabel.Visible = 'on';
            app.RangeLabel.Visible = 'on';
            app.Label.Text = '';
            drawnow
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.Label.Text = 'Wait';
                drawnow
            if app.MaxEditField.Value < app.MinEditField.Value
                app.MaxEditField.Value = app.MinEditField.Value;
            end
            max_all = max(app.data_raw(:));
            if app.MaxEditField.Value > max_all
                app.MaxEditField.Value = max_all;
            end
            update_range(app);
            app.Label.Text = '';
            drawnow
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            app.Label.Text = 'Wait';
            drawnow
            if app.MaxEditField.Value < app.MinEditField.Value
                app.MinEditField.Value = app.MaxEditField.Value;
            end
            min_all = min(app.data_raw(:));
            if app.MinEditField.Value < min_all
                app.MinEditField.Value = min_all;
            end
            update_range(app);
            app.Label.Text = '';
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenVoltageDataMenu = uimenu(app.FileMenu);
            app.OpenVoltageDataMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenVoltageDataMenuSelected, true);
            app.OpenVoltageDataMenu.Text = 'Open Voltage Data';
            app.OpenCalciumDataMenu = uimenu(app.FileMenu);
            app.OpenCalciumDataMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenCalciumDataMenuSelected, true);
            app.OpenCalciumDataMenu.Text = 'Open Calcium Data';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'ms';
            app.Button_back = uibutton(app.UIFigure, 'push');
            app.Button_back.ButtonPushedFcn = createCallbackFcn(app, @Button_backPushed, true);
            app.Button_back.Text = '<';
            app.Button_forward = uibutton(app.UIFigure, 'push');
            app.Button_forward.ButtonPushedFcn = createCallbackFcn(app, @Button_forwardPushed, true);
            app.Button_forward.Text = '>';
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't =';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.tEditField.ValueDisplayFormat = '%5.3f';
            app.tEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.Label = uilabel(app.StatusPanel);
            app.Label.Text = '';
            app.StepsLabel = uilabel(app.UIFigure);
            app.StepsLabel.Text = 'Steps =';
            app.StepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsEditField.Limits = [1 Inf];
            app.StepsEditField.RoundFractionalValues = 'on';
            app.StepsEditField.Value = 1;
            app.samplesLabel = uilabel(app.UIFigure);
            app.samplesLabel.Text = 'samples';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Somatic activity')
            xlabel(app.UIAxes2, 't (ms)')
            ylabel(app.UIAxes2, '')
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.ValueDisplayFormat = '%5.4f';
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField.ValueDisplayFormat = '%5.4f';
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.RangeLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end