classdef Quick_Guide < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        StepsButtonGroup   matlab.ui.container.ButtonGroup
        modButton          matlab.ui.control.ToggleButton
        modGenButton       matlab.ui.control.ToggleButton
        EselectButton      matlab.ui.control.ToggleButton
        exportLocButton    matlab.ui.control.ToggleButton
        EsimButton         matlab.ui.control.ToggleButton
        couplingButton     matlab.ui.control.ToggleButton
        waveformButton     matlab.ui.control.ToggleButton
        neuronButton       matlab.ui.control.ToggleButton
        calciumButton      matlab.ui.control.ToggleButton
        visualizeButton    matlab.ui.control.ToggleButton
        tutorialButton     matlab.ui.control.ToggleButton
        prev_step          matlab.ui.control.Button
        next_step          matlab.ui.control.Button
        HTML               matlab.ui.control.HTML
        urlTutorialButton  matlab.ui.control.Button
        realisticButton    matlab.ui.control.Button
        uniformButton      matlab.ui.control.Button
        urlFEMButton       matlab.ui.control.Button
        urlSimButton       matlab.ui.control.Button
        modHelpButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        Current_step % keep track of the step
        Script_dir % directory of this script
        uniform_E
    end
    
    methods (Access = private)
        
        function update_instruction(app)
            switch app.Current_step
                case 1 % Compile mod
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'on';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br><br><br>Compile the <b>.mod</b> files inside'...
                        ' the following folders:<br><br><b>''Model_Generation\Eyal Jarsky_files\lib_mech''</b>'...
                        '<br><b>''Model_Generation\Eyal_files\lib_mech''</b>'...
                        '<br><br>Note that you only need to do this once on each computer. '...
                        'Skip this step if you have already compiled the mod files. <br><br> For more information on '...
                        'mod files and how to use them, see below:</center> </p>'];
                case 2 % Generate model
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br>Generate the model by following '...
                        'the instructions for each model type:<br><br><b>Jarsky CA1 pyramidal cell</b>: '...
                        'place the desired morphology file (<b>.swc</b>) inside <b>''Model_Generation\morphos''</b> '...
                        ' folder or use one of the example morphology files provided in that folder (<b>cell_#.swc</b>). '...
                        'Then, change your MATLAB directory to <b>''Model_Generation''</b> folder and run '...
                        ' <span style="background-color:#C0C0C0"><b>Jarsky_model(''??.swc'');</b></span>, where <b>??</b>'...
                        ' is the name of the desired morphology file, and follow the prompts.<br><br> <b>Eyal cortical '...
                        'pyramidal cell</b>: change your MATLAB directory to <b>''Model_Generation''</b> folder and run '...
                        '<span style="background-color:#C0C0C0"><b>Eyal_model_060303();</b></span> with no arguments.'...
                        '<br> The generated model is placed inside <b>''Models\model_name''</b> folder. All the next '...
                        'steps are model-specific and therefore operate within this folder.</center> </p>'];
                case 3 % Selec E-field type
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'on';
                    app.uniformButton.Visible = 'on';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br><br><br><br><br><br><br> Select the electric field '...
                        'type you wish to use:</center> </p>'];
                case 4 % Export segment locs
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br><br><br><br><br><br><br>Export the neuron segment '...
                        'coordinates by running <b>''Code\NEURON\save_locations.hoc''</b> in NEURON environment.'...
                        '<br><br><b>Tip:</b> run NEURON scripts by double-clicking on the file on Windows or running the command '...
                        '<span style="background-color:#C0C0C0"><b>nrniv file_name.hoc</b></span> in the terminal on macOS '...
                        'and Linux.</center> </p>'];
                case 5 % Simulate E-field
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'on';
                    app.urlSimButton.Visible = 'on';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br><br><br><br><br><br><br>Simulate the spatial distribution '...
                        'of the electric fields in SimNIBS. Refer to the following resources for more details:</center> </p>'];
                case 6 % Couple E-field
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br><br><br><br><br><br><br>Change your MATLAB directory to'...
                        ' <b>''Code\E-Field_Coupling''</b>, run <span style="background-color:#C0C0C0"><b>couple_gui();'...
                        '</b></span>, and follow the prompts.</center> </p>'];
                case 7 % TMS waveform
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br><br><br><br><br><br><br>Change your MATLAB directory to'...
                        ' <b>''Code\TMS_Waveform''</b>, run <span style="background-color:#C0C0C0"><b>TMS_Waveform();'...
                        '</b></span>, and follow the prompts.</center> </p>'];
                case 8 % NEURON sim
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br><br><br><br><br>Select NEURON simulation parameters'...
                        ' by running <b>''Code\NEURON\GUI_params.hoc''</b> in NEURON environment and following the prompts. '...
                        'Then, run the simulation by running <b>''Code\NEURON\TMS_script.hoc''</b> in NEURON environment.'...
                        ' The window will automatically close upon successful completion of the simulation.'...
                        '<br><br><b>Tip:</b> run NEURON scripts by double-clicking on the file on Windows or running the command '...
                        '<span style="background-color:#C0C0C0"><b>nrniv file_name.hoc</b></span> in the terminal on macOS '...
                        'and Linux.</center></p>'];
                case 9 % Calcium sim
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br><br><br><br><br><br><br>Change your MATLAB directory to '...
                        '<b>''Code\Calcium''</b>, run <span style="background-color:#C0C0C0"><b>run(''calcium_simulation_setup.mlapp'');'...
                        '</b></span>, enter the location of UG4 software and the parameters for calcium simulation. Then click on the '...
                        '<b>Convert NEURON files</b> button to convert the files to a format compatible with UG4. Afterward, click on the'...
                        ' <b>Generate script</b>, <b>Save script</b>, and <b>Run script</b> buttons respectively and wait '...
                        'for the simulation to finish.</center> </p>'];
                case 10 % Visualization
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br><br><br><br><br><br><br>Change your MATLAB directory to ' ...
                        '<b>''Code\Visualization''</b>, run <span style="background-color:#C0C0C0"><b>run(''visualization.mlapp'');'...
                        '</b></span>. Click on File and select <b>Open Voltage Data</b> and <b>Open Calcium Data</b> to visualize '...
                        'the 3D voltage and calcium results across time respectively.</center></p>'];
                case 11 % Full tutorial
                    app.urlTutorialButton.Visible = 'on';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = ['<p> <center><br><br><br><br><br><br><br><br><br> For more information refer to '...
                        'the full PDF tutorial:</center> </p>'];
                otherwise
                    app.urlTutorialButton.Visible = 'off';
                    app.realisticButton.Visible = 'off';
                    app.uniformButton.Visible = 'off';
                    app.urlFEMButton.Visible = 'off';
                    app.urlSimButton.Visible = 'off';
                    app.modHelpButton.Visible = 'off';
                    app.HTML.HTMLSource = '<p></p>';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Current_step = 1;
            app.uniform_E = 0;
            app.UIFigure.Name='NeMo-TMS Guide';
            app.Script_dir = fileparts(mfilename('fullpath'));
            cd(app.Script_dir);
            update_instruction(app);
        end

        % Button pushed function: prev_step
        function prev_stepPushed(app, event)
            if app.Current_step == 7 && app.uniform_E == 1
                app.Current_step = 3;
                app.StepsButtonGroup.Buttons(app.Current_step).Value = 1;
                update_instruction(app);
            elseif app.Current_step > 1
                app.Current_step = app.Current_step - 1;
                app.StepsButtonGroup.Buttons(app.Current_step).Value = 1;
                update_instruction(app);
            end
        end

        % Button pushed function: next_step
        function next_stepButtonPushed(app, event)
            if app.Current_step == 3 && app.uniform_E == 1
                app.Current_step = 7;
                app.StepsButtonGroup.Buttons(app.Current_step).Value = 1;
                update_instruction(app);
            elseif app.Current_step < length(app.StepsButtonGroup.Buttons)
                app.Current_step = app.Current_step + 1;
                app.StepsButtonGroup.Buttons(app.Current_step).Value = 1;
                update_instruction(app);
            end
        end

        % Selection changed function: StepsButtonGroup
        function StepsButtonGroupSelectionChanged(app, event)
            selectedButton = app.StepsButtonGroup.SelectedObject;
            app.Current_step = find(app.StepsButtonGroup.Buttons==selectedButton);
            update_instruction(app);
        end

        % Button pushed function: urlTutorialButton
        function urlTutorialButtonPushed(app, event)
            web('https://github.com/OpitzLab/NeMo-TMS');
        end

        % Button pushed function: realisticButton
        function realisticButtonPushed(app, event)
            app.uniform_E = 0;
            app.Current_step = 4;
            app.StepsButtonGroup.Buttons(app.Current_step).Value = 1;
            update_instruction(app);
        end

        % Button pushed function: uniformButton
        function uniformButtonPushed(app, event)
            app.uniform_E = 1;
            app.Current_step = 7;
            app.StepsButtonGroup.Buttons(app.Current_step).Value = 1;
            update_instruction(app);
        end

        % Button pushed function: urlSimButton
        function urlSimButtonPushed(app, event)
            web('https://simnibs.github.io/simnibs/build/html/tutorial/tutorial.html');
        end

        % Button pushed function: urlFEMButton
        function urlFEMButtonPushed(app, event)
            web('https://zenodo.org/record/4009465');
        end

        % Button pushed function: modHelpButton
        function modHelpButtonPushed(app, event)
            web('https://www.neuron.yale.edu/phpBB/viewtopic.php?t=3263');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StepsButtonGroup = uibuttongroup(app.UIFigure);
            app.StepsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StepsButtonGroupSelectionChanged, true);
            app.StepsButtonGroup.Title = 'Steps';
            app.modButton = uitogglebutton(app.StepsButtonGroup);
            app.modButton.Text = 'Compile mod files';
            app.modButton.Value = true;
            app.modGenButton = uitogglebutton(app.StepsButtonGroup);
            app.modGenButton.Text = 'Model generation';
            app.EselectButton = uitogglebutton(app.StepsButtonGroup);
            app.EselectButton.Text = 'Electric field type';
            app.exportLocButton = uitogglebutton(app.StepsButtonGroup);
            app.exportLocButton.Text = {'Export neuron'; 'segment location'};
            app.EsimButton = uitogglebutton(app.StepsButtonGroup);
            app.EsimButton.Text = {'Realistic electric'; 'field simulation'};
            app.couplingButton = uitogglebutton(app.StepsButtonGroup);
            app.couplingButton.Text = {'Realistic electric'; 'field coupling'};
            app.waveformButton = uitogglebutton(app.StepsButtonGroup);
            app.waveformButton.Text = {'TMS waveform'; 'generation'};
            app.neuronButton = uitogglebutton(app.StepsButtonGroup);
            app.neuronButton.Text = 'NEURON simulation';
            app.calciumButton = uitogglebutton(app.StepsButtonGroup);
            app.calciumButton.Text = 'Calcium simulation';
            app.visualizeButton = uitogglebutton(app.StepsButtonGroup);
            app.visualizeButton.Text = 'Visualization';
            app.tutorialButton = uitogglebutton(app.StepsButtonGroup);
            app.tutorialButton.Text = 'Full tutorial';
            app.prev_step = uibutton(app.UIFigure, 'push');
            app.prev_step.ButtonPushedFcn = createCallbackFcn(app, @prev_stepPushed, true);
            app.prev_step.Text = 'Prev';
            app.next_step = uibutton(app.UIFigure, 'push');
            app.next_step.ButtonPushedFcn = createCallbackFcn(app, @next_stepButtonPushed, true);
            app.next_step.Text = 'Next';
            app.HTML = uihtml(app.UIFigure);
            app.urlTutorialButton = uibutton(app.UIFigure, 'push');
            app.urlTutorialButton.ButtonPushedFcn = createCallbackFcn(app, @urlTutorialButtonPushed, true);
            app.urlTutorialButton.Text = 'Tutorial';
            app.realisticButton = uibutton(app.UIFigure, 'push');
            app.realisticButton.ButtonPushedFcn = createCallbackFcn(app, @realisticButtonPushed, true);
            app.realisticButton.Text = {'Spatially realistic'; 'electric field'};
            app.uniformButton = uibutton(app.UIFigure, 'push');
            app.uniformButton.ButtonPushedFcn = createCallbackFcn(app, @uniformButtonPushed, true);
            app.uniformButton.Text = {'Spatially Uniform'; 'electric field'};
            app.urlFEMButton = uibutton(app.UIFigure, 'push');
            app.urlFEMButton.ButtonPushedFcn = createCallbackFcn(app, @urlFEMButtonPushed, true);
            app.urlFEMButton.Text = {'Translational FEM'; 'models'};
            app.urlSimButton = uibutton(app.UIFigure, 'push');
            app.urlSimButton.ButtonPushedFcn = createCallbackFcn(app, @urlSimButtonPushed, true);
            app.urlSimButton.Text = {'Online SimNIBS'; 'Tutorial'};
            app.modHelpButton = uibutton(app.UIFigure, 'push');
            app.modHelpButton.ButtonPushedFcn = createCallbackFcn(app, @modHelpButtonPushed, true);
            app.modHelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Quick_Guide

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end